classdef DesignAeronef < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DesigndaronefUIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ParamtredemissionTab            matlab.ui.container.Tab
        WPayloadlbLabel                 matlab.ui.control.Label
        WPayload                        matlab.ui.control.NumericEditField
        CalculerPayload                 matlab.ui.control.Button
        PAXEditFieldLabel               matlab.ui.control.Label
        PAX                             matlab.ui.control.NumericEditField
        CrewEditFieldLabel              matlab.ui.control.Label
        Crew                            matlab.ui.control.NumericEditField
        WbaglbLabel                     matlab.ui.control.Label
        Wbag                            matlab.ui.control.NumericEditField
        WpeoplelbLabel                  matlab.ui.control.Label
        Wpeople                         matlab.ui.control.NumericEditField
        RangeNMLabel                    matlab.ui.control.Label
        Range                           matlab.ui.control.NumericEditField
        GnralTab                        matlab.ui.container.Tab
        MachdeCroisireEditFieldLabel    matlab.ui.control.Label
        M_cruise                        matlab.ui.control.NumericEditField
        AltitudedeCroisireftEditFieldLabel  matlab.ui.control.Label
        H_cruise                        matlab.ui.control.NumericEditField
        AllongementAREditFieldLabel     matlab.ui.control.Label
        AR                              matlab.ui.control.NumericEditField
        Chargelairedbutdecroisirelbft2EditFieldLabel  matlab.ui.control.Label
        WS_croisiere                    matlab.ui.control.NumericEditField
        PoussetotaleEditFieldLabel      matlab.ui.control.Label
        Thrust_tot                      matlab.ui.control.NumericEditField
        TSCFEditFieldLabel              matlab.ui.control.Label
        TSFC                            matlab.ui.control.NumericEditField
        TxdeMonteEditFieldLabel         matlab.ui.control.Label
        tx_climb                        matlab.ui.control.NumericEditField
        CD0EditFieldLabel               matlab.ui.control.Label
        CD0                             matlab.ui.control.NumericEditField
        TempdattenteminEditFieldLabel   matlab.ui.control.Label
        T_loiter                        matlab.ui.control.NumericEditField
        FractiondessenceavantatterissageEditFieldLabel  matlab.ui.control.Label
        Ffuel_res                       matlab.ui.control.NumericEditField
        FractiondessencedanslesconduitesEditFieldLabel  matlab.ui.control.Label
        Ffuel_trap                      matlab.ui.control.NumericEditField
        RecalculerDesign                matlab.ui.control.Button
        DistancedatterisageLabel        matlab.ui.control.Label
        Dlanding                        matlab.ui.control.NumericEditField
        AileTab_2                       matlab.ui.container.Tab
        AngledeflcheLeradEditFieldLabel  matlab.ui.control.Label
        Angle_fleche_Le                 matlab.ui.control.NumericEditField
        EffilementLabel                 matlab.ui.control.Label
        Effilement                      matlab.ui.control.NumericEditField
        QLabel                          matlab.ui.control.Label
        Q                               matlab.ui.control.NumericEditField
        ProfildelaileEditFieldLabel     matlab.ui.control.Label
        PolaireAile                     matlab.ui.control.EditField
        tcmaxLabel                      matlab.ui.control.Label
        epmax                           matlab.ui.control.NumericEditField
        xcmaxLabel                      matlab.ui.control.Label
        xcm                             matlab.ui.control.NumericEditField
        AngledeflchetcmaxradLabel       matlab.ui.control.Label
        Angle_fleche_epmax              matlab.ui.control.NumericEditField
        FuselageTab_2                   matlab.ui.container.Tab
        LongueurdufuselageftEditFieldLabel  matlab.ui.control.Label
        l                               matlab.ui.control.NumericEditField
        GrandaxeEllipseftEditFieldLabel  matlab.ui.control.Label
        Aside                           matlab.ui.control.NumericEditField
        PetitaxeEllipseftEditFieldLabel  matlab.ui.control.Label
        Atop                            matlab.ui.control.NumericEditField
        kEtatdesurfaceEditFieldLabel    matlab.ui.control.Label
        k_surface                       matlab.ui.control.NumericEditField
        QfuselageLabel                  matlab.ui.control.Label
        Q_fuselage                      matlab.ui.control.NumericEditField
        EmpenageTab_2                   matlab.ui.container.Tab
        HorizontalLabel                 matlab.ui.control.Label
        VerticalLabel                   matlab.ui.control.Label
        CHTEditFieldLabel               matlab.ui.control.Label
        C_HT                            matlab.ui.control.NumericEditField
        CVTEditFieldLabel               matlab.ui.control.Label
        C_VT                            matlab.ui.control.NumericEditField
        DistancelAileftLabel            matlab.ui.control.Label
        lht                             matlab.ui.control.NumericEditField
        DistancelAileftLabel_2          matlab.ui.control.Label
        lvt                             matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        ARLabel                         matlab.ui.control.Label
        AR_HT                           matlab.ui.control.NumericEditField
        EffilementLabel_2               matlab.ui.control.Label
        effilement_ht                   matlab.ui.control.NumericEditField
        ARLabel_2                       matlab.ui.control.Label
        AR_VT                           matlab.ui.control.NumericEditField
        EffilementLabel_3               matlab.ui.control.Label
        effilement_vt                   matlab.ui.control.NumericEditField
        QLabel_2                        matlab.ui.control.Label
        Q_ht                            matlab.ui.control.NumericEditField
        xcmaxLabel_2                    matlab.ui.control.Label
        xcm_ht                          matlab.ui.control.NumericEditField
        tcmaxLabel_2                    matlab.ui.control.Label
        tcm_ht                          matlab.ui.control.NumericEditField
        QLabel_3                        matlab.ui.control.Label
        Q_vt                            matlab.ui.control.NumericEditField
        xcmaxLabel_3                    matlab.ui.control.Label
        xcm_vt                          matlab.ui.control.NumericEditField
        tcmaxLabel_3                    matlab.ui.control.Label
        tcm_vt                          matlab.ui.control.NumericEditField
        AngledeFlcheLabel               matlab.ui.control.Label
        Angle_fleche_HT                 matlab.ui.control.NumericEditField
        AngledeFlchetcmaxLabel          matlab.ui.control.Label
        Angle_tcmax_HT                  matlab.ui.control.NumericEditField
        AngledeFlcheLabel_2             matlab.ui.control.Label
        Angle_fleche_VT                 matlab.ui.control.NumericEditField
        AngledeFlchetcmaxLabel_2        matlab.ui.control.Label
        Angle_tcmax_VT                  matlab.ui.control.NumericEditField
        ProfildelempennageEditFieldLabel  matlab.ui.control.Label
        profil_empennage                matlab.ui.control.EditField
        TabGroup2                       matlab.ui.container.TabGroup
        PoidsTab                        matlab.ui.container.Tab
        PoidsVideLabel                  matlab.ui.control.Label
        Wempty                          matlab.ui.control.NumericEditField
        PoidsdessenceLabel              matlab.ui.control.Label
        Wfuel                           matlab.ui.control.NumericEditField
        PoidsaudcollageLabel            matlab.ui.control.Label
        Wto                             matlab.ui.control.NumericEditField
        PoidslatterisageLabel           matlab.ui.control.Label
        Wlanding                        matlab.ui.control.NumericEditField
        ChargelairTab                   matlab.ui.container.Tab
        WSdcollageEditFieldLabel        matlab.ui.control.Label
        WS_TO                           matlab.ui.control.NumericEditField
        WSMonteEditFieldLabel           matlab.ui.control.Label
        WS_climb                        matlab.ui.control.NumericEditField
        WSCroissireEditFieldLabel       matlab.ui.control.Label
        WS_cruise                       matlab.ui.control.NumericEditField
        WSLandingEditFieldLabel         matlab.ui.control.Label
        WS_landing                      matlab.ui.control.NumericEditField
        PousseTab                       matlab.ui.container.Tab
        PoussencessaireaudcollageEditFieldLabel  matlab.ui.control.Label
        T_to                            matlab.ui.control.NumericEditField
        TWaudcollageLabel               matlab.ui.control.Label
        TW_to                           matlab.ui.control.NumericEditField
        AileTab                         matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        GometrieTab                     matlab.ui.container.Tab
        Surfaceft2EditFieldLabel        matlab.ui.control.Label
        S                               matlab.ui.control.NumericEditField
        EnvergureftLabel                matlab.ui.control.Label
        b                               matlab.ui.control.NumericEditField
        CrftLabel                       matlab.ui.control.Label
        Cr                              matlab.ui.control.NumericEditField
        CtftLabel                       matlab.ui.control.Label
        Ct                              matlab.ui.control.NumericEditField
        CmftLabel                       matlab.ui.control.Label
        Cmoy                            matlab.ui.control.NumericEditField
        AngledeflcheC4radLabel          matlab.ui.control.Label
        Angle_fleche_c4                 matlab.ui.control.NumericEditField
        AngledeflcheC2radLabel          matlab.ui.control.Label
        Angle_fleche_c2                 matlab.ui.control.NumericEditField
        AerodynamismeTab                matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        AngledeCalageradEditFieldLabel  matlab.ui.control.Label
        angle_calage_aile               matlab.ui.control.NumericEditField
        acoeffdiraileEditFieldLabel     matlab.ui.control.Label
        a_aile                          matlab.ui.control.NumericEditField
        a0coeffdirprofilEditFieldLabel  matlab.ui.control.Label
        a0_aile                         matlab.ui.control.NumericEditField
        CLdecroisireLabel               matlab.ui.control.Label
        CL_cruise                       matlab.ui.control.NumericEditField
        ReynoldsLabel                   matlab.ui.control.Label
        Re_aile                         matlab.ui.control.NumericEditField
        CD0Label                        matlab.ui.control.Label
        CD0_aile                        matlab.ui.control.NumericEditField
        FuselageTab                     matlab.ui.container.Tab
        ReynoldsEditFieldLabel          matlab.ui.control.Label
        Re_fuslage                      matlab.ui.control.NumericEditField
        CD0Label_2                      matlab.ui.control.Label
        CD0_fuslage                     matlab.ui.control.NumericEditField
        EmpenageTab                     matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        GomtrieTab                      matlab.ui.container.Tab
        HorizontalLabel_2               matlab.ui.control.Label
        VerticalLabel_2                 matlab.ui.control.Label
        Surfaceft2EditFieldLabel_2      matlab.ui.control.Label
        S_HT                            matlab.ui.control.NumericEditField
        EnvergureftEditFieldLabel       matlab.ui.control.Label
        b_ht                            matlab.ui.control.NumericEditField
        CordelemplantureftEditFieldLabel  matlab.ui.control.Label
        cr_ht                           matlab.ui.control.NumericEditField
        CordeauboutftEditFieldLabel     matlab.ui.control.Label
        ct_ht                           matlab.ui.control.NumericEditField
        CordeMoyenneftEditFieldLabel    matlab.ui.control.Label
        cm_ht                           matlab.ui.control.NumericEditField
        Surfaceft2EditField_2Label      matlab.ui.control.Label
        S_VT                            matlab.ui.control.NumericEditField
        EnvergureftEditField_2Label     matlab.ui.control.Label
        b_vt                            matlab.ui.control.NumericEditField
        CordelemplantureftEditField_2Label  matlab.ui.control.Label
        cr_vt                           matlab.ui.control.NumericEditField
        CordeauboutftEditField_2Label   matlab.ui.control.Label
        ct_vt                           matlab.ui.control.NumericEditField
        CordeMoyenneftEditField_2Label  matlab.ui.control.Label
        cm_vt                           matlab.ui.control.NumericEditField
        AngledeFlcheC4Label             matlab.ui.control.Label
        Angleflechec4_ht                matlab.ui.control.NumericEditField
        AngledeFlcheC2Label             matlab.ui.control.Label
        Angleflechec2_ht                matlab.ui.control.NumericEditField
        AngledeFlcheC4Label_2           matlab.ui.control.Label
        Angleflechec4_vt                matlab.ui.control.NumericEditField
        AngledeFlcheC2Label_2           matlab.ui.control.Label
        Angleflechec2_vt                matlab.ui.control.NumericEditField
        ArodynamismeTab                 matlab.ui.container.Tab
        HorizontalLabel_3               matlab.ui.control.Label
        VerticalLabel_3                 matlab.ui.control.Label
        Cd0Label                        matlab.ui.control.Label
        Cd0_ht                          matlab.ui.control.NumericEditField
        Cd0Label_2                      matlab.ui.control.Label
        Cd0_vt                          matlab.ui.control.NumericEditField
        aLabel                          matlab.ui.control.Label
        a_ht                            matlab.ui.control.NumericEditField
        aLabel_2                        matlab.ui.control.Label
        a_vt                            matlab.ui.control.NumericEditField
        UIAxesEmpennage                 matlab.ui.control.UIAxes
        TainedOndeTab                   matlab.ui.container.Tab
        UIAxesDw                        matlab.ui.control.UIAxes
        Ma_critique                     matlab.ui.control.NumericEditField
        MachLabel                       matlab.ui.control.Label
        Ma_DD                           matlab.ui.control.NumericEditField
        M_100                           matlab.ui.control.NumericEditField
        M_105                           matlab.ui.control.NumericEditField
        M_120                           matlab.ui.control.NumericEditField
        MaCrLabel                       matlab.ui.control.Label
        MaDDLabel                       matlab.ui.control.Label
        Cdw_cr                          matlab.ui.control.NumericEditField
        CdwLabel                        matlab.ui.control.Label
        Cdw_dd                          matlab.ui.control.NumericEditField
        Cdw_100                         matlab.ui.control.NumericEditField
        Cdw_105                         matlab.ui.control.NumericEditField
        Cdw_120                         matlab.ui.control.NumericEditField
        dC_dM_cr                        matlab.ui.control.NumericEditField
        dCdwdMachLabel                  matlab.ui.control.Label
        dC_dM_dd                        matlab.ui.control.NumericEditField
        dC_dM_100                       matlab.ui.control.NumericEditField
        dC_dM_105                       matlab.ui.control.NumericEditField
        dC_dM_120                       matlab.ui.control.NumericEditField
        cdw_coeff_cr                    matlab.ui.control.NumericEditField
        CoeffLabel                      matlab.ui.control.Label
        cdw_coeff_dd                    matlab.ui.control.NumericEditField
        cdw_coeff_100                   matlab.ui.control.NumericEditField
        cdw_coeff_105                   matlab.ui.control.NumericEditField
        cdw_coeff_120                   matlab.ui.control.NumericEditField
        CDwencroisireEditFieldLabel     matlab.ui.control.Label
        Cdw                             matlab.ui.control.NumericEditField
        MachDDCl0EditFieldLabel         matlab.ui.control.Label
        Ma_DD_Cl0                       matlab.ui.control.NumericEditField
        PerformanceTab                  matlab.ui.container.Tab
        FinessemaxLabel                 matlab.ui.control.Label
        Finesse                         matlab.ui.control.NumericEditField
        MachRelLabel                    matlab.ui.control.Label
        M_real                          matlab.ui.control.NumericEditField
        AngledeMontradLabel             matlab.ui.control.Label
        angle_climb                     matlab.ui.control.NumericEditField
        CLmaxLandingLabel               matlab.ui.control.Label
        CL_max_landing                  matlab.ui.control.NumericEditField
        CLmaxTakeoffLabel               matlab.ui.control.Label
        CL_max_TO                       matlab.ui.control.NumericEditField
        DistancedeDcollageftLabel       matlab.ui.control.Label
        DTO                             matlab.ui.control.NumericEditField
        VitessededcrochageftsLabel      matlab.ui.control.Label
        Vstall                          matlab.ui.control.NumericEditField
        VitessededcollageftsLabel       matlab.ui.control.Label
        VTo                             matlab.ui.control.NumericEditField
        TempratureKLabel                matlab.ui.control.Label
        T                               matlab.ui.control.NumericEditField
        VitessedeCroisireftsLabel       matlab.ui.control.Label
        V_cruise                        matlab.ui.control.NumericEditField
        CD0delavionLabel                matlab.ui.control.Label
        Cd0_avion                       matlab.ui.control.NumericEditField
        Finesse_iter                    matlab.ui.control.NumericEditField
        ThLabel                         matlab.ui.control.Label
        CalLabel                        matlab.ui.control.Label
        RsultatsdeCalculLabel           matlab.ui.control.Label
        ParamtresUtilisateurLabel       matlab.ui.control.Label
    end

    methods (Static)
        function [a0,alpha0,incidence,Cl,test] = PortanceProfil(Pol)
            [~,i_fin_max] = max(Pol.data(:,2)./Pol.data(:,3));
            incidence=Pol.data(:,1);
            incidencefmax=(incidence(i_fin_max));
            Cl=Pol.data(:,2);
            
            test = logical(incidence>-10).*logical(incidence<5);
            
            [xData, yData] = prepareCurveData( incidence(logical(test))*pi/180, Cl(logical(test)));
            incidence=incidence*pi/180;
            % Set up fittype and options.
            ft = fittype( 'poly1' );
            opts = fitoptions( 'Method', 'LinearLeastSquares');
            
            % Fit model to data.
            fitresult = fit( xData, yData, ft, opts );
            p = coeffvalues(fitresult);
            a0 = p(1);
            alpha0 = -p(2)/p(1); %rad
        end
        function mu = viscosity(T)
            mu = ((8.8848e-15*(T^3)-3.2398e-11*(T^2)+6.2657e-8*T+2.3543e-6)/9.81)*(1/3.28)^2*2.2048;
        end
        function [CD0,Remoy] = CD0Empenage(rho,Cm,Vmoy,T,M_real,Angle_fleche,xcm,tc,M_cruise,Angle_fleche_tcmax,S,Q,Sw)
            Remoy = rho*Cm*Vmoy/DesignAeronef.viscosity(T);
            Cf = DesignAeronef.CFriction(Remoy,M_real*cos(Angle_fleche));
            Ff = 1.10*(1+0.6/xcm*tc+100*(tc)^4)*(1.34*M_cruise^0.18*(cos(Angle_fleche_tcmax))^0.28);
            Swet = S*(1.977+0.52*(tc));
            CD0 = Cf*Ff*Q*Swet/Sw;
        end
        function [S,b,cr,ct,cmoy] = GeometrieAile(S,AR,effilement)
            b = sqrt(AR*S);
            cr = 2*S/(b*(1+effilement));
            ct = effilement*cr;
            cmoy = 2*cr/3*(1+effilement+effilement^2)/(1+effilement);
        end
        function cf = CFriction(Re,M)
            cf = 0.455/((log(Re)/log(10))^2.58*(1+0.144*M^2)^0.65);
        end
        function [a0,a,incidence,Cl,Angleflechec4,Angleflechec2,Cl3D,Cl2D,CL_cruise,angleCalageAile] = Portance(profilfile,angleFlecheLe,effilement,b,Cr,AR,S,WS_cruise,V_cruise)
            global rho;
            Pol = importdata(profilfile);
            [a0,alpha0,incidence,Cl,test] = DesignAeronef.PortanceProfil(Pol);
            Angleflechec4 = atan(tan(angleFlecheLe)-Cr/(2*b)*(1-effilement));  %angle de flèche au quart de la corde (rad)
            Angleflechec2 = atan(tan(angleFlecheLe)-Cr/b*(1-effilement));  %angle de flèche au mileu de la corde (rad)
            
            a = a0*cos(Angleflechec2)/(sqrt(1+(a0*cos(Angleflechec2)/(pi*AR))^2)+a0*cos(Angleflechec2)/(pi*AR));
            
            Cl3D=a*(incidence(logical(test))-alpha0);
            Cl2D=a0*(incidence(logical(test))-alpha0);
            CL_cruise = (WS_cruise*S)/(0.5*rho*V_cruise^2*S);
            
            if CL_cruise<max(Cl3D)
                angleCalageAile = alpha0+ CL_cruise/a;
            else
                angleCalageAile = inf;
            end      
        end
    end
    methods (Access = private)
    
        function [Vmoy,Vmach]=poids(app)
            global rho;
            fclimb = 1 -0.04*app.M_cruise.Value;
            fdec = 0.975;
            k=1/(pi()*app.AR.Value*0.8);
            [ app.Wto.Value, app.Wfuel.Value, app.Wempty.Value ] = itertow('jet-transport',app.M_cruise.Value, app.H_cruise.Value, app.AR.Value, app.TSFC.Value, app.T_loiter.Value, app.Ffuel_res.Value, app.Ffuel_trap.Value, app.WPayload.Value, app.CD0.Value,app.Range.Value);
        
           app.Wlanding.Value = app.Wempty.Value + app.Wfuel.Value*(app.Ffuel_res.Value+app.Ffuel_trap.Value)+app.WPayload.Value;
           %Wmoy = (Wlanding+Wto)/2;
           Wmoy = app.Wto.Value*fdec*fclimb;
           
           app.S.Value=Wmoy/app.WS_croisiere.Value;                       %Charge à l'aire en milieu de mission
           [T, a, P, rho] = atmosisa(app.H_cruise.Value/3.28);    %masse volumique au Hcruise
           rho = rho*0.06854/3.28^3;                    %(slug/ft^3)
           
           Vmoy = sqrt(2/rho*Wmoy/app.S.Value)*(3*k/app.CD0.Value)^0.25;    %vitesse moyenne lors du vol (ft/s)
           Vmach=app.M_cruise.Value*sqrt(1.4*1716.5*T*9/5);       %vitesse désirée pour le Mach du choix de designer (ft/s)
           app.M_real.Value = Vmoy/sqrt(1.4*1716.5*T*9/5);
        end
        
        function ratio = performances(app)
            global k;
            
            app.WS_landing.Value = app.Wlanding.Value/app.S.Value; % Charge à l'aire à l'attérissage
            app.WS_TO.Value = app.Wto.Value/app.S.Value; %Charge à l'aire au décollage
           
            app.CL_max_landing.Value = app.WS_landing.Value / ((app.Dlanding.Value - 400) / 118);  % Calcul du CLmax,L = CLmax
            
            % Calcul du CLmax,TO
            app.CL_max_TO.Value = 0.8 * app.CL_max_landing.Value;
            
            q = .5*density(0)*app.VTo.Value^2;
            app.T_to.Value = q*app.S.Value*(app.CD0.Value+k*app.CL_max_TO.Value^2)+app.Wto.Value*sin(app.angle_climb.Value);
            app.TW_to.Value = app.T_to.Value/app.Wto.Value;
            WS_climb_int = (app.TW_to.Value - sin(app.angle_climb.Value))^2-4*app.CD0.Value*cos(app.angle_climb.Value)^2*k;
            app.WS_climb.Value = q/(2*cos(app.angle_climb.Value)^2*k)*((app.TW_to.Value - sin(app.angle_climb.Value)+sqrt(WS_climb_int)));
            
            % Calcul de la distance de decollage
            app.TW_to.Value = app.Thrust_tot.Value/app.Wto.Value;
            app.DTO.Value = 20.9*(app.WS_TO.Value * (1/app.CL_max_TO.Value) * (1/app.TW_to.Value)) + 87*sqrt((app.WS_TO.Value * (1/app.CL_max_TO.Value) * (1/app.TW_to.Value))*app.TW_to.Value);
            
            % Calcul de la vitesse de decrochage
            app.Vstall.Value = sqrt((2*app.Wto.Value)/(density(0)*app.S.Value*app.CL_max_landing.Value));
            
            % Calcul de la vitesse de decollage
            app.VTo.Value = 1.1*app.Vstall.Value;
            
            ratio=(app.Thrust_tot.Value/(2*app.Wto.Value))/(sin(app.angle_climb.Value)+sqrt(app.CD0.Value*k));% verif puissance avec panne de 1 moteur
            
            fcruise = 1 / exp(app.Range.Value*app.TSFC.Value*6080/(vitesse(app.M_cruise.Value,app.H_cruise.Value)*app.Finesse.Value*3600));
            fl=1/exp(app.T_loiter.Value*app.TSFC.Value/(app.Finesse.Value*60));
            fclimb = 1 -0.04*app.M_cruise.Value;
            fdec = 0.975;
            
            app.WS_climb.Value = app.WS_climb.Value/(fdec);
            app.WS_cruise.Value = app.WS_croisiere.Value/(fdec*fclimb);
            app.WS_landing.Value = app.WS_landing.Value/(fdec*fclimb*fcruise*fl);
        end
        
        
        function PortanceAile(app)
            app.T.Value = tempatmstd(app.H_cruise.Value);
            
            [app.S.Value,app.b.Value,app.Cr.Value,app.Ct.Value,app.Cmoy.Value] = DesignAeronef.GeometrieAile(app.S.Value,app.AR.Value,app.Effilement.Value);
            
            [app.a0_aile.Value,app.a_aile.Value,incidence,Cl,app.Angle_fleche_c4.Value,app.Angle_fleche_c2.Value,Cl3D,Cl2D,app.CL_cruise.Value,app.angle_calage_aile.Value] = DesignAeronef.Portance(app.PolaireAile.Value,app.Angle_fleche_Le.Value,app.Effilement.Value,app.b.Value,app.Cr.Value,app.AR.Value,app.S.Value,app.WS_croisiere.Value,app.V_cruise.Value);
            test = logical(incidence>-10*pi/180).*logical(incidence<5*pi/180);
            plot(app.UIAxes,incidence(logical(test)).*180/pi, Cl3D,'-',incidence(logical(test)).*180/pi, Cl2D,'--',incidence.*180/pi,Cl,'k:');
            legend(app.UIAxes,'CL 3D','CL 2D','Profil 4412','location','NorthWest');
        end
        
        function PortanceEmpenage(app)
            [~,app.a_ht.Value,incidence,Cl,app.Angleflechec4_ht.Value,app.Angleflechec2_ht.Value,Cl3D,Cl2D,~,~] = DesignAeronef.Portance(app.profil_empennage.Value,app.Angle_fleche_HT.Value,app.effilement_ht.Value,app.b_ht.Value,app.cr_ht.Value,app.AR_HT.Value,app.S_HT.Value,app.WS_croisiere.Value,app.V_cruise.Value);
            test = logical(incidence>-10*pi/180).*logical(incidence<5*pi/180);
            hold(app.UIAxesEmpennage,'on');
            plot(app.UIAxesEmpennage,incidence(logical(test)).*180/pi, Cl3D,'-',incidence(logical(test)).*180/pi, Cl2D,'--',incidence.*180/pi,Cl,'k:');
            legend(app.UIAxesEmpennage,'CL 3D','CL 2D','Profil 4412','location','NorthWest');
            [~,app.a_vt.Value,incidence,Cl,app.Angleflechec4_vt.Value,app.Angleflechec2_vt.Value,Cl3D,Cl2D,~,~] = DesignAeronef.Portance(app.profil_empennage.Value,app.Angle_fleche_VT.Value,app.effilement_vt.Value,app.b_vt.Value,app.cr_vt.Value,app.AR_VT.Value,app.S_VT.Value,app.WS_croisiere.Value,app.V_cruise.Value);
            test = logical(incidence>-10*pi/180).*logical(incidence<5*pi/180);
            plot(app.UIAxesEmpennage,incidence(logical(test)).*180/pi, Cl3D,'-',incidence(logical(test)).*180/pi, Cl2D,'--',incidence.*180/pi,Cl,'k:');
            legend(app.UIAxesEmpennage,'CL 3D','CL 2D','Profil 4412','location','NorthWest');
            hold(app.UIAxesEmpennage,'off');
        end
        
        function TraineeAile(app)
            global rho;
            app.Re_aile.Value = rho*app.Cmoy.Value*app.V_cruise.Value/DesignAeronef.viscosity(app.T.Value);
            
            Cf = DesignAeronef.CFriction(app.Re_aile.Value,app.M_real.Value*cos(app.Angle_fleche_Le.Value));
            FF =(1+0.6/app.xcm.Value*(app.epmax.Value)+100*app.epmax.Value^4)*(1.34*app.M_real.Value^0.18*(cos(app.Angle_fleche_epmax.Value))^0.28);
            
            Swet = app.S.Value*(1.977+0.52*(app.epmax.Value));   %On va tracer l'aile en fonction du profil choisi et aller mesurer ça dans Solidworks
            
            app.CD0_aile.Value = Cf*FF*app.Q.Value*Swet/app.S.Value;
            
        end
        
        
        function Fuslage(app)
            global rho;
            d=min([app.Aside.Value app.Atop.Value]);     %choix designer

            Re_eff = 38.21*(app.l.Value/app.k_surface.Value)^1.053; % Reynolds effectif / rugosite (M<1)
            app.Re_fuslage.Value = min([rho*app.l.Value*app.V_cruise.Value/DesignAeronef.viscosity(app.T.Value) Re_eff]);
            Cf = DesignAeronef.CFriction(app.Re_fuslage.Value,app.M_real.Value);
            FF_fuselage = 1+60/(app.l.Value/d)^3+app.l.Value/(400*d);
            Swet_fuselage = pi*((app.Aside.Value+app.Atop.Value)/2)*app.l.Value;
            app.CD0_fuslage.Value=Cf*FF_fuselage*app.Q_fuselage.Value*Swet_fuselage/app.S.Value;
        end
        
        function TraineeOnde(app)
            Cdw = 4*9/2*pi/app.l.Value^2*(pi*app.Aside.Value*app.Atop.Value)^2/app.S.Value;

            app.Ma_DD.Value = app.Ma_DD_Cl0.Value-0.05*app.CL_cruise.Value;
            app.Ma_critique.Value = app.Ma_DD.Value-0.08; % Def de boeing.
            app.Cdw_100.Value = Cdw/2;
            app.Cdw_105.Value = Cdw;
            app.Cdw_120.Value = Cdw;
            
            Dw_pts = [app.Ma_critique.Value app.Ma_DD.Value 1 1.05 1.2; 0 2e-3 Cdw/2 Cdw Cdw];
            
            % détermination graphique de la trainé d'onde
            xdif = diff(Dw_pts(1,:));
            ydif = diff(Dw_pts(2,:));
            directeur = ydif./xdif;
            directeur =[0 app.cdw_coeff_dd.Value*directeur(1) app.cdw_coeff_100.Value*directeur(2) app.cdw_coeff_105.Value*directeur(3) -(1.2^2-1)^(-1.5)];
            
            app.dC_dM_cr.Value = directeur(1);
            app.dC_dM_dd.Value = directeur(2);
            app.dC_dM_100.Value = directeur(3);
            app.dC_dM_105.Value = directeur(4);
            app.dC_dM_120.Value = directeur(5);
            
            coeff = [Dw_pts;directeur];
            P_coeff = zeros(4);
            cla(app.UIAxesDw);
            hold(app.UIAxesDw,'on');
            for i=1:4
                x1 = coeff(1,i);
                x2 = coeff(1,i+1);
                y1 = coeff(2,i);
                y2 = coeff(2,i+1);
                d1 = coeff(3,i);
                d2 = coeff(3,i+1);
                
                A_coeff = [(x2-x1)^3 (x2-x1)^2;3*(x2-x1)^2 2*(x2-x1)];
                B_coeff = [y2-d1*(x2-x1)-y1;d2-d1];
                P_coeff(i,1:2) = A_coeff\B_coeff;
                P_coeff(i,3) = d1;
                P_coeff(i,4) = y1;
                xx = x1:(x2-x1)/1e2:x2;
                %plot(xx,d1*(xx-x1)+y1,'k');
                %plot(xx,d2*(xx-x2)+y2,'k');
                
                yy = P_coeff(i,1)*(xx-x1).^3+P_coeff(i,2)*(xx-x1).^2+P_coeff(i,3)*(xx-x1)+P_coeff(i,4);
                plot(app.UIAxesDw,xx,yy,'b');
                
            end
            plot(app.UIAxesDw,Dw_pts(1,:),Dw_pts(2,:),'k+');
            
            [~,I]=sort(abs(Dw_pts(1,:)-app.M_real.Value));
            app.Cdw.Value = P_coeff(I(1),1)*(app.M_real.Value-Dw_pts(1,I(1)))^3+P_coeff(I(1),2)*(app.M_real.Value-Dw_pts(1,I(1)))^2+P_coeff(I(1),3)*(app.M_real.Value-Dw_pts(1,I(1)))+P_coeff(I(1),4);
            plot(app.UIAxesDw,app.M_real.Value,app.Cdw.Value,'dr');
            hold(app.UIAxesDw,'off');
        end
        function Empenage(app)
            global rho;
            s_ht = app.C_HT.Value*app.Cmoy.Value*app.S.Value/app.lht.Value;
            [app.S_HT.Value,app.b_ht.Value,app.cr_ht.Value,app.ct_ht.Value,app.cm_ht.Value] = DesignAeronef.GeometrieAile(s_ht,app.AR_HT.Value,app.effilement_ht.Value);
            s_vt = app.C_VT.Value*app.b.Value*app.S.Value/app.lvt.Value;
            [app.S_VT.Value,app.b_vt.Value,app.cr_vt.Value,app.ct_vt.Value,app.cm_vt.Value] = DesignAeronef.GeometrieAile(s_vt,app.AR_VT.Value,app.effilement_vt.Value);

            [app.Cd0_ht.Value,~]=DesignAeronef.CD0Empenage(rho,app.cm_ht.Value,app.V_cruise.Value,app.T.Value,app.M_real.Value,app.Angle_fleche_HT.Value,app.xcm_ht.Value,app.tcm_ht.Value,app.M_real.Value,app.Angle_tcmax_HT.Value,app.S_HT.Value,app.Q_ht.Value,app.S.Value);
            [app.Cd0_vt.Value,~]=DesignAeronef.CD0Empenage(rho,app.cm_vt.Value,app.V_cruise.Value,app.T.Value,app.M_real.Value,app.Angle_fleche_HT.Value,app.xcm_vt.Value,app.tcm_vt.Value,app.M_real.Value,app.Angle_tcmax_VT.Value,app.S_VT.Value,app.Q_vt.Value,app.S.Value);
        end
        
        function Recalculate(app)
            global k;
            k=1/(pi()*app.AR.Value*0.8);
            performances(app);
            PortanceAile(app);
            TraineeAile(app);
            Fuslage(app);
            TraineeOnde(app);
            Empenage(app);
            PortanceEmpenage(app);
            app.Cd0_avion.Value = app.Cd0_vt.Value+app.Cd0_ht.Value+app.CD0_aile.Value+app.Cdw.Value+app.CD0_fuslage.Value;
            app.Finesse_iter.Value = app.CL_cruise.Value/(app.Cd0_avion.Value+k*app.CL_cruise.Value^2);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Projet;
            global A CD0 k  Charge_Ailaire SL Crew  Fuel_trap  H_cruise Fuel_res  M_cruise   PAX  Range T_loiter Thrust_tot  TSFC  tx_montee  Wbag  Wpeople;
            global EffAile AngflecheLe Q_aile;
            
            app.PAX.Value = PAX;
            app.Crew.Value = Crew;
            app.Wbag.Value = Wbag;
            app.Wpeople.Value = Wpeople;
            app.Range.Value = Range;
            
            app.M_cruise.Value = M_cruise;
            app.H_cruise.Value = H_cruise;
            app.AR.Value = A;
            app.WS_croisiere.Value = Charge_Ailaire;
            app.Thrust_tot.Value = Thrust_tot;
            app.TSFC.Value = TSFC;
            app.tx_climb.Value = tx_montee;
            app.CD0.Value=CD0;
            app.T_loiter.Value=T_loiter;
            app.Ffuel_res.Value=Fuel_res;
            app.Ffuel_trap.Value=Fuel_trap;
            
            app.Dlanding.Value= SL;
            app.Q.Value = Q_aile;
            app.Angle_fleche_Le.Value = AngflecheLe;
            app.Effilement.Value = EffAile;
            
            global Angle_fleche_epmax xcm epmax;
            
            app.Angle_fleche_epmax.Value=Angle_fleche_epmax;
            app.epmax.Value = epmax;
            app.xcm.Value = xcm;
            
            global Atop Aside l k_surface Q_fuselage;
            
            app.Atop.Value= Atop;
            app.Aside.Value=Aside;
            app.l.Value = l;
            app.k_surface.Value=k_surface;
            app.Q_fuselage.Value = Q_fuselage;
            
            global Ma_DD_Cl0 coeff_dC_d_M;
            
            app.Ma_DD_Cl0.Value = Ma_DD_Cl0;
            app.cdw_coeff_dd.Value = coeff_dC_d_M(1);
            app.cdw_coeff_100.Value = coeff_dC_d_M(2);
            app.cdw_coeff_105.Value = coeff_dC_d_M(3);
            
            global C_VT lvt AR_VT effilement_vt Angle_fleche_VT Angle_tcmax_VT Q_vt xcm_vt tc_vt;
            
            app.C_VT.Value = C_VT;
            app.lvt.Value = lvt;
            app.AR_VT.Value = AR_VT;
            app.effilement_vt.Value = effilement_vt; 
            app.Angle_fleche_VT.Value = Angle_fleche_VT;
            app.Angle_tcmax_VT.Value = Angle_tcmax_VT;
            app.Q_vt.Value = Q_vt;
            app.xcm_vt.Value = xcm_vt;
            app.tcm_vt.Value = tc_vt;
            
            global C_HT lht AR_HT effilement_ht Angle_fleche_HT Angle_tcmax_HT Q_ht xcm_ht tc_ht;
            app.C_HT.Value =  C_HT;
            app.lht.Value = lht;
            app.AR_HT.Value = AR_HT;
            app.effilement_ht.Value = effilement_ht; 
            app.Angle_fleche_HT.Value = Angle_fleche_HT;
            app.Angle_tcmax_HT.Value = Angle_tcmax_HT;
            app.Q_ht.Value = Q_ht;
            app.xcm_ht.Value = xcm_ht;
            app.tcm_ht.Value = tc_ht;


            k=1/(pi()*app.AR.Value*0.8);
            app.CalculerPayloadPushed();
            app.RecalculerDesignButtonPushed();
            Recalculate(app);
            
        end

        % Callback function: CalculerPayload, Crew, PAX, Range, Wbag, 
        % Wpeople
        function CalculerPayloadPushed(app, event)

            
            app.WPayload.Value = app.PAX.Value*(app.Wpeople.Value+app.Wbag.Value)+app.Crew.Value*(app.Wpeople.Value+10);

            [Vmoy,Vmach] = poids(app);
               
            while Vmoy/Vmach > 1.02;                         %While qui décroit l'altitude moyenne pour rapprocher la vitesse moyenne du vol au mach de designer
                app.H_cruise.Value = app.H_cruise.Value - 20;
               [Vmoy,Vmach] = poids(app);
            end
            app.V_cruise.Value = Vmoy;
            Recalculate(app);
        end

        % Callback function: AR, CD0, Ffuel_res, Ffuel_trap, 
        % H_cruise, M_cruise, RecalculerDesign, TSFC, T_loiter, 
        % Thrust_tot, WS_croisiere, tx_climb
        function RecalculerDesignButtonPushed(app, event)
            global k
            app.angle_climb.Value= atan(app.tx_climb.Value);

            f = 1/sqrt(4*k*app.CD0.Value);
            app.Finesse.Value = f;
            app.CalculerPayloadPushed();
           Recalculate(app);
        end

        % Callback function: Angle_fleche_Le, Angle_fleche_epmax, 
        % Effilement, PolaireAile, Q, epmax, xcm
        function AileDesignChanged(app, event)
           Recalculate(app);
        end

        % Value changed function: Aside, Atop, Q_fuselage, k_surface, 
        % l
        function FuselageDesignChanged(app, event)
            Recalculate(app);
        end

        % Value changed function: Ma_DD_Cl0, cdw_coeff_100, 
        % cdw_coeff_105, cdw_coeff_dd
        function Ma_DD_Cl0ValueChanged(app, event)
            Recalculate(app);
            
        end

        % Value changed function: AR_HT, AR_VT, Angle_fleche_HT, 
        % Angle_fleche_VT, Angle_tcmax_HT, Angle_tcmax_VT, C_HT, C_VT, 
        % Q_ht, Q_vt, effilement_ht, effilement_vt, lht, lvt, tcm_ht, 
        % tcm_vt, xcm_ht, xcm_vt
        function EmpengageChanged(app, event)
            Recalculate(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DesigndaronefUIFigure = uifigure;
            app.DesigndaronefUIFigure.Name = 'Design d''aéronef';
            app.TabGroup = uitabgroup(app.DesigndaronefUIFigure);
            app.ParamtredemissionTab = uitab(app.TabGroup);
            app.ParamtredemissionTab.Title = 'Paramètre de mission';
            app.WPayloadlbLabel = uilabel(app.ParamtredemissionTab);
            app.WPayloadlbLabel.Text = 'WPayload [lb]';
            app.WPayload = uieditfield(app.ParamtredemissionTab, 'numeric');
            app.WPayload.ValueDisplayFormat = '%.2f';
            app.WPayload.Editable = 'off';
            app.CalculerPayload = uibutton(app.ParamtredemissionTab, 'push');
            app.CalculerPayload.ButtonPushedFcn = createCallbackFcn(app, @CalculerPayloadPushed, true);
            app.CalculerPayload.Text = 'Calculer Payload';
            app.PAXEditFieldLabel = uilabel(app.ParamtredemissionTab);
            app.PAXEditFieldLabel.Text = 'PAX';
            app.PAX = uieditfield(app.ParamtredemissionTab, 'numeric');
            app.PAX.ValueDisplayFormat = '%.0f';
            app.PAX.ValueChangedFcn = createCallbackFcn(app, @CalculerPayloadPushed, true);
            app.CrewEditFieldLabel = uilabel(app.ParamtredemissionTab);
            app.CrewEditFieldLabel.Text = 'Crew';
            app.Crew = uieditfield(app.ParamtredemissionTab, 'numeric');
            app.Crew.ValueDisplayFormat = '%.0f';
            app.Crew.ValueChangedFcn = createCallbackFcn(app, @CalculerPayloadPushed, true);
            app.WbaglbLabel = uilabel(app.ParamtredemissionTab);
            app.WbaglbLabel.Text = 'Wbag [lb]';
            app.Wbag = uieditfield(app.ParamtredemissionTab, 'numeric');
            app.Wbag.ValueDisplayFormat = '%.0f';
            app.Wbag.ValueChangedFcn = createCallbackFcn(app, @CalculerPayloadPushed, true);
            app.WpeoplelbLabel = uilabel(app.ParamtredemissionTab);
            app.WpeoplelbLabel.Text = 'Wpeople [lb]';
            app.Wpeople = uieditfield(app.ParamtredemissionTab, 'numeric');
            app.Wpeople.ValueDisplayFormat = '%.0f';
            app.Wpeople.ValueChangedFcn = createCallbackFcn(app, @CalculerPayloadPushed, true);
            app.RangeNMLabel = uilabel(app.ParamtredemissionTab);
            app.RangeNMLabel.Text = 'Range [NM]';
            app.Range = uieditfield(app.ParamtredemissionTab, 'numeric');
            app.Range.ValueDisplayFormat = '%.0f';
            app.Range.ValueChangedFcn = createCallbackFcn(app, @CalculerPayloadPushed, true);
            app.GnralTab = uitab(app.TabGroup);
            app.GnralTab.Title = 'Général';
            app.MachdeCroisireEditFieldLabel = uilabel(app.GnralTab);
            app.MachdeCroisireEditFieldLabel.Text = 'Mach de Croisière';
            app.M_cruise = uieditfield(app.GnralTab, 'numeric');
            app.M_cruise.ValueDisplayFormat = '%.3f';
            app.M_cruise.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.AltitudedeCroisireftEditFieldLabel = uilabel(app.GnralTab);
            app.AltitudedeCroisireftEditFieldLabel.Text = 'Altitude de Croisière (ft)';
            app.H_cruise = uieditfield(app.GnralTab, 'numeric');
            app.H_cruise.ValueDisplayFormat = '%.2f';
            app.H_cruise.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.AllongementAREditFieldLabel = uilabel(app.GnralTab);
            app.AllongementAREditFieldLabel.Text = 'Allongement (AR)';
            app.AR = uieditfield(app.GnralTab, 'numeric');
            app.AR.ValueDisplayFormat = '%.2f';
            app.AR.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.Chargelairedbutdecroisirelbft2EditFieldLabel = uilabel(app.GnralTab);
            app.Chargelairedbutdecroisirelbft2EditFieldLabel.Text = 'Charge à l''aire(début de croisière) [lb/ft^2]';
            app.WS_croisiere = uieditfield(app.GnralTab, 'numeric');
            app.WS_croisiere.ValueDisplayFormat = '%.2f';
            app.WS_croisiere.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.PoussetotaleEditFieldLabel = uilabel(app.GnralTab);
            app.PoussetotaleEditFieldLabel.Text = 'Poussée totale';
            app.Thrust_tot = uieditfield(app.GnralTab, 'numeric');
            app.Thrust_tot.ValueDisplayFormat = '%.2f';
            app.Thrust_tot.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.TSCFEditFieldLabel = uilabel(app.GnralTab);
            app.TSCFEditFieldLabel.Text = 'TSCF';
            app.TSFC = uieditfield(app.GnralTab, 'numeric');
            app.TSFC.ValueDisplayFormat = '%.3f';
            app.TSFC.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.TxdeMonteEditFieldLabel = uilabel(app.GnralTab);
            app.TxdeMonteEditFieldLabel.Text = 'Tx de Montée';
            app.tx_climb = uieditfield(app.GnralTab, 'numeric');
            app.tx_climb.ValueDisplayFormat = '%.3f';
            app.tx_climb.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.CD0EditFieldLabel = uilabel(app.GnralTab);
            app.CD0EditFieldLabel.Text = 'CD0';
            app.CD0 = uieditfield(app.GnralTab, 'numeric');
            app.CD0.ValueDisplayFormat = '%.3f';
            app.CD0.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.TempdattenteminEditFieldLabel = uilabel(app.GnralTab);
            app.TempdattenteminEditFieldLabel.Text = 'Temp d''attente  [min]';
            app.T_loiter = uieditfield(app.GnralTab, 'numeric');
            app.T_loiter.ValueDisplayFormat = '%.0f';
            app.T_loiter.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.FractiondessenceavantatterissageEditFieldLabel = uilabel(app.GnralTab);
            app.FractiondessenceavantatterissageEditFieldLabel.Text = 'Fraction d''essence avant atterissage';
            app.Ffuel_res = uieditfield(app.GnralTab, 'numeric');
            app.Ffuel_res.ValueDisplayFormat = '%.3f';
            app.Ffuel_res.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.FractiondessencedanslesconduitesEditFieldLabel = uilabel(app.GnralTab);
            app.FractiondessencedanslesconduitesEditFieldLabel.Text = 'Fraction d''essence dans les conduites';
            app.Ffuel_trap = uieditfield(app.GnralTab, 'numeric');
            app.Ffuel_trap.ValueDisplayFormat = '%.3f';
            app.Ffuel_trap.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.RecalculerDesign = uibutton(app.GnralTab, 'push');
            app.RecalculerDesign.ButtonPushedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.RecalculerDesign.Text = 'Recalculer';
            app.DistancedatterisageLabel = uilabel(app.GnralTab);
            app.DistancedatterisageLabel.Text = 'Distance d''atterisage';
            app.Dlanding = uieditfield(app.GnralTab, 'numeric');
            app.Dlanding.ValueDisplayFormat = '%.2f';
            app.AileTab_2 = uitab(app.TabGroup);
            app.AileTab_2.Title = 'Aile';
            app.AngledeflcheLeradEditFieldLabel = uilabel(app.AileTab_2);
            app.AngledeflcheLeradEditFieldLabel.Text = 'Angle de flèche Le [rad]';
            app.Angle_fleche_Le = uieditfield(app.AileTab_2, 'numeric');
            app.Angle_fleche_Le.ValueDisplayFormat = '%.3f';
            app.Angle_fleche_Le.ValueChangedFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.EffilementLabel = uilabel(app.AileTab_2);
            app.EffilementLabel.Text = 'Effilement';
            app.Effilement = uieditfield(app.AileTab_2, 'numeric');
            app.Effilement.ValueDisplayFormat = '%.3f';
            app.Effilement.ValueChangedFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.QLabel = uilabel(app.AileTab_2);
            app.QLabel.Text = 'Q';
            app.Q = uieditfield(app.AileTab_2, 'numeric');
            app.Q.ValueDisplayFormat = '%.2f';
            app.Q.ValueChangedFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.ProfildelaileEditFieldLabel = uilabel(app.AileTab_2);
            app.ProfildelaileEditFieldLabel.Text = 'Profil de l''aile';
            app.PolaireAile = uieditfield(app.AileTab_2, 'text');
            app.PolaireAile.ValueChangingFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.PolaireAile.Value = 'xf-naca4412-il-1000000-n5.txt';
            app.tcmaxLabel = uilabel(app.AileTab_2);
            app.tcmaxLabel.Text = 't/c max';
            app.epmax = uieditfield(app.AileTab_2, 'numeric');
            app.epmax.ValueDisplayFormat = '%.2f';
            app.epmax.ValueChangedFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.xcmaxLabel = uilabel(app.AileTab_2);
            app.xcmaxLabel.Text = 'x/c max';
            app.xcm = uieditfield(app.AileTab_2, 'numeric');
            app.xcm.ValueDisplayFormat = '%.2f';
            app.xcm.ValueChangedFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.AngledeflchetcmaxradLabel = uilabel(app.AileTab_2);
            app.AngledeflchetcmaxradLabel.Text = 'Angle de flèche t/c max [rad]';
            app.Angle_fleche_epmax = uieditfield(app.AileTab_2, 'numeric');
            app.Angle_fleche_epmax.ValueDisplayFormat = '%.3f';
            app.Angle_fleche_epmax.ValueChangedFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.FuselageTab_2 = uitab(app.TabGroup);
            app.FuselageTab_2.Title = 'Fuselage';
            app.LongueurdufuselageftEditFieldLabel = uilabel(app.FuselageTab_2);
            app.LongueurdufuselageftEditFieldLabel.Text = 'Longueur du fuselage (ft) ';
            app.l = uieditfield(app.FuselageTab_2, 'numeric');
            app.l.ValueDisplayFormat = '%.2f';
            app.l.ValueChangedFcn = createCallbackFcn(app, @FuselageDesignChanged, true);
            app.GrandaxeEllipseftEditFieldLabel = uilabel(app.FuselageTab_2);
            app.GrandaxeEllipseftEditFieldLabel.Text = 'Grand axe (Ellipse) (ft) ';
            app.Aside = uieditfield(app.FuselageTab_2, 'numeric');
            app.Aside.ValueDisplayFormat = '%.2f';
            app.Aside.ValueChangedFcn = createCallbackFcn(app, @FuselageDesignChanged, true);
            app.PetitaxeEllipseftEditFieldLabel = uilabel(app.FuselageTab_2);
            app.PetitaxeEllipseftEditFieldLabel.Text = 'Petit axe (Ellipse) (ft) ';
            app.Atop = uieditfield(app.FuselageTab_2, 'numeric');
            app.Atop.ValueDisplayFormat = '%.2f';
            app.Atop.ValueChangedFcn = createCallbackFcn(app, @FuselageDesignChanged, true);
            app.kEtatdesurfaceEditFieldLabel = uilabel(app.FuselageTab_2);
            app.kEtatdesurfaceEditFieldLabel.Text = 'k - Etat de surface';
            app.k_surface = uieditfield(app.FuselageTab_2, 'numeric');
            app.k_surface.ValueDisplayFormat = '%e';
            app.k_surface.ValueChangedFcn = createCallbackFcn(app, @FuselageDesignChanged, true);
            app.QfuselageLabel = uilabel(app.FuselageTab_2);
            app.QfuselageLabel.Text = 'Q fuselage';
            app.Q_fuselage = uieditfield(app.FuselageTab_2, 'numeric');
            app.Q_fuselage.ValueDisplayFormat = '%.2f';
            app.Q_fuselage.ValueChangedFcn = createCallbackFcn(app, @FuselageDesignChanged, true);
            app.EmpenageTab_2 = uitab(app.TabGroup);
            app.EmpenageTab_2.Title = 'Empenage';
            app.HorizontalLabel = uilabel(app.EmpenageTab_2);
            app.HorizontalLabel.Text = 'Horizontal';
            app.VerticalLabel = uilabel(app.EmpenageTab_2);
            app.VerticalLabel.Text = 'Vertical';
            app.CHTEditFieldLabel = uilabel(app.EmpenageTab_2);
            app.CHTEditFieldLabel.Text = 'C HT';
            app.C_HT = uieditfield(app.EmpenageTab_2, 'numeric');
            app.C_HT.ValueDisplayFormat = '%.3f';
            app.C_HT.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.CVTEditFieldLabel = uilabel(app.EmpenageTab_2);
            app.CVTEditFieldLabel.Text = 'C VT';
            app.C_VT = uieditfield(app.EmpenageTab_2, 'numeric');
            app.C_VT.ValueDisplayFormat = '%.3f';
            app.C_VT.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.DistancelAileftLabel = uilabel(app.EmpenageTab_2);
            app.DistancelAileftLabel.Text = 'Distance à l''Aile [ft]';
            app.lht = uieditfield(app.EmpenageTab_2, 'numeric');
            app.lht.ValueDisplayFormat = '%.3f';
            app.lht.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.DistancelAileftLabel_2 = uilabel(app.EmpenageTab_2);
            app.DistancelAileftLabel_2.Text = 'Distance à l''Aile [ft]';
            app.lvt = uieditfield(app.EmpenageTab_2, 'numeric');
            app.lvt.ValueDisplayFormat = '%.3f';
            app.lvt.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.Label = uilabel(app.EmpenageTab_2);
            app.Label.Text = 'ATTENSION les distances sont prise entre les 2 centres aérodynamiques ';
            app.ARLabel = uilabel(app.EmpenageTab_2);
            app.ARLabel.Text = 'AR';
            app.AR_HT = uieditfield(app.EmpenageTab_2, 'numeric');
            app.AR_HT.ValueDisplayFormat = '%.2f';
            app.AR_HT.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.EffilementLabel_2 = uilabel(app.EmpenageTab_2);
            app.EffilementLabel_2.Text = 'Effilement';
            app.effilement_ht = uieditfield(app.EmpenageTab_2, 'numeric');
            app.effilement_ht.ValueDisplayFormat = '%.2f';
            app.effilement_ht.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.ARLabel_2 = uilabel(app.EmpenageTab_2);
            app.ARLabel_2.Text = 'AR';
            app.AR_VT = uieditfield(app.EmpenageTab_2, 'numeric');
            app.AR_VT.ValueDisplayFormat = '%.2f';
            app.AR_VT.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.EffilementLabel_3 = uilabel(app.EmpenageTab_2);
            app.EffilementLabel_3.Text = 'Effilement';
            app.effilement_vt = uieditfield(app.EmpenageTab_2, 'numeric');
            app.effilement_vt.ValueDisplayFormat = '%.2f';
            app.effilement_vt.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.QLabel_2 = uilabel(app.EmpenageTab_2);
            app.QLabel_2.Text = 'Q';
            app.Q_ht = uieditfield(app.EmpenageTab_2, 'numeric');
            app.Q_ht.ValueDisplayFormat = '%.2f';
            app.Q_ht.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.xcmaxLabel_2 = uilabel(app.EmpenageTab_2);
            app.xcmaxLabel_2.Text = 'x/c max';
            app.xcm_ht = uieditfield(app.EmpenageTab_2, 'numeric');
            app.xcm_ht.ValueDisplayFormat = '%.2f';
            app.xcm_ht.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.tcmaxLabel_2 = uilabel(app.EmpenageTab_2);
            app.tcmaxLabel_2.Text = 't/c max';
            app.tcm_ht = uieditfield(app.EmpenageTab_2, 'numeric');
            app.tcm_ht.ValueDisplayFormat = '%.2f';
            app.tcm_ht.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.QLabel_3 = uilabel(app.EmpenageTab_2);
            app.QLabel_3.Text = 'Q';
            app.Q_vt = uieditfield(app.EmpenageTab_2, 'numeric');
            app.Q_vt.ValueDisplayFormat = '%.2f';
            app.Q_vt.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.xcmaxLabel_3 = uilabel(app.EmpenageTab_2);
            app.xcmaxLabel_3.Text = 'x/c max';
            app.xcm_vt = uieditfield(app.EmpenageTab_2, 'numeric');
            app.xcm_vt.ValueDisplayFormat = '%.2f';
            app.xcm_vt.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.tcmaxLabel_3 = uilabel(app.EmpenageTab_2);
            app.tcmaxLabel_3.Text = 't/c max';
            app.tcm_vt = uieditfield(app.EmpenageTab_2, 'numeric');
            app.tcm_vt.ValueDisplayFormat = '%.2f';
            app.tcm_vt.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.AngledeFlcheLabel = uilabel(app.EmpenageTab_2);
            app.AngledeFlcheLabel.Text = 'Angle de Flèche';
            app.Angle_fleche_HT = uieditfield(app.EmpenageTab_2, 'numeric');
            app.Angle_fleche_HT.ValueDisplayFormat = '%.2f';
            app.Angle_fleche_HT.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.AngledeFlchetcmaxLabel = uilabel(app.EmpenageTab_2);
            app.AngledeFlchetcmaxLabel.Text = 'Angle de Flèche (t/c max)';
            app.Angle_tcmax_HT = uieditfield(app.EmpenageTab_2, 'numeric');
            app.Angle_tcmax_HT.ValueDisplayFormat = '%.2f';
            app.Angle_tcmax_HT.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.AngledeFlcheLabel_2 = uilabel(app.EmpenageTab_2);
            app.AngledeFlcheLabel_2.Text = 'Angle de Flèche';
            app.Angle_fleche_VT = uieditfield(app.EmpenageTab_2, 'numeric');
            app.Angle_fleche_VT.ValueDisplayFormat = '%.2f';
            app.Angle_fleche_VT.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.AngledeFlchetcmaxLabel_2 = uilabel(app.EmpenageTab_2);
            app.AngledeFlchetcmaxLabel_2.Text = 'Angle de Flèche (t/c max)';
            app.Angle_tcmax_VT = uieditfield(app.EmpenageTab_2, 'numeric');
            app.Angle_tcmax_VT.ValueDisplayFormat = '%.2f';
            app.Angle_tcmax_VT.ValueChangedFcn = createCallbackFcn(app, @EmpengageChanged, true);
            app.ProfildelempennageEditFieldLabel = uilabel(app.EmpenageTab_2);
            app.ProfildelempennageEditFieldLabel.Text = 'Profil de l''empennage';
            app.profil_empennage = uieditfield(app.EmpenageTab_2, 'text');
            app.profil_empennage.Value = 'NACA 0014_T1_Al 0.00_M0.30_N12.0.txt';
            app.TabGroup2 = uitabgroup(app.DesigndaronefUIFigure);
            app.PoidsTab = uitab(app.TabGroup2);
            app.PoidsTab.Title = 'Poids';
            app.PoidsVideLabel = uilabel(app.PoidsTab);
            app.PoidsVideLabel.Text = 'Poids à Vide';
            app.Wempty = uieditfield(app.PoidsTab, 'numeric');
            app.Wempty.ValueDisplayFormat = '%.2f';
            app.Wempty.Editable = 'off';
            app.PoidsdessenceLabel = uilabel(app.PoidsTab);
            app.PoidsdessenceLabel.Text = 'Poids d''essence';
            app.Wfuel = uieditfield(app.PoidsTab, 'numeric');
            app.Wfuel.ValueDisplayFormat = '%.2f';
            app.Wfuel.Editable = 'off';
            app.PoidsaudcollageLabel = uilabel(app.PoidsTab);
            app.PoidsaudcollageLabel.Text = 'Poids au décollage';
            app.Wto = uieditfield(app.PoidsTab, 'numeric');
            app.Wto.ValueDisplayFormat = '%.2f';
            app.Wto.Editable = 'off';
            app.PoidslatterisageLabel = uilabel(app.PoidsTab);
            app.PoidslatterisageLabel.Text = 'Poids à l''atterisage';
            app.Wlanding = uieditfield(app.PoidsTab, 'numeric');
            app.Wlanding.ValueDisplayFormat = '%.2f';
            app.Wlanding.Editable = 'off';
            app.ChargelairTab = uitab(app.TabGroup2);
            app.ChargelairTab.Title = 'Charge à l''air';
            app.WSdcollageEditFieldLabel = uilabel(app.ChargelairTab);
            app.WSdcollageEditFieldLabel.Text = 'W/S décollage';
            app.WS_TO = uieditfield(app.ChargelairTab, 'numeric');
            app.WS_TO.ValueDisplayFormat = '%.2f';
            app.WS_TO.Editable = 'off';
            app.WSMonteEditFieldLabel = uilabel(app.ChargelairTab);
            app.WSMonteEditFieldLabel.Text = 'W/S Montée';
            app.WS_climb = uieditfield(app.ChargelairTab, 'numeric');
            app.WS_climb.ValueDisplayFormat = '%.2f';
            app.WS_climb.Editable = 'off';
            app.WSCroissireEditFieldLabel = uilabel(app.ChargelairTab);
            app.WSCroissireEditFieldLabel.Text = 'W/S Croissière';
            app.WS_cruise = uieditfield(app.ChargelairTab, 'numeric');
            app.WS_cruise.ValueDisplayFormat = '%.2f';
            app.WS_cruise.Editable = 'off';
            app.WSLandingEditFieldLabel = uilabel(app.ChargelairTab);
            app.WSLandingEditFieldLabel.Text = 'W/S Landing';
            app.WS_landing = uieditfield(app.ChargelairTab, 'numeric');
            app.WS_landing.ValueDisplayFormat = '%.2f';
            app.WS_landing.Editable = 'off';
            app.PousseTab = uitab(app.TabGroup2);
            app.PousseTab.Title = 'Poussée';
            app.PoussencessaireaudcollageEditFieldLabel = uilabel(app.PousseTab);
            app.PoussencessaireaudcollageEditFieldLabel.Text = 'Poussée nécessaire au décollage';
            app.T_to = uieditfield(app.PousseTab, 'numeric');
            app.T_to.ValueDisplayFormat = '%.2f';
            app.T_to.Editable = 'off';
            app.TWaudcollageLabel = uilabel(app.PousseTab);
            app.TWaudcollageLabel.Text = 'T/W au décollage';
            app.TW_to = uieditfield(app.PousseTab, 'numeric');
            app.TW_to.ValueDisplayFormat = '%.3f';
            app.TW_to.Editable = 'off';
            app.AileTab = uitab(app.TabGroup2);
            app.AileTab.Title = 'Aile';
            app.TabGroup3 = uitabgroup(app.AileTab);
            app.GometrieTab = uitab(app.TabGroup3);
            app.GometrieTab.Title = 'Géometrie';
            app.Surfaceft2EditFieldLabel = uilabel(app.GometrieTab);
            app.Surfaceft2EditFieldLabel.Text = 'Surface [ft^2]';
            app.S = uieditfield(app.GometrieTab, 'numeric');
            app.S.ValueDisplayFormat = '%.2f';
            app.S.Editable = 'off';
            app.EnvergureftLabel = uilabel(app.GometrieTab);
            app.EnvergureftLabel.Text = 'Envergure [ft]';
            app.b = uieditfield(app.GometrieTab, 'numeric');
            app.b.ValueDisplayFormat = '%.2f';
            app.b.Editable = 'off';
            app.CrftLabel = uilabel(app.GometrieTab);
            app.CrftLabel.Text = 'Cr [ft]';
            app.Cr = uieditfield(app.GometrieTab, 'numeric');
            app.Cr.ValueDisplayFormat = '%.2f';
            app.Cr.Editable = 'off';
            app.CtftLabel = uilabel(app.GometrieTab);
            app.CtftLabel.Text = 'Ct[ft]';
            app.Ct = uieditfield(app.GometrieTab, 'numeric');
            app.Ct.ValueDisplayFormat = '%.2f';
            app.Ct.Editable = 'off';
            app.CmftLabel = uilabel(app.GometrieTab);
            app.CmftLabel.Text = 'Cm [ft]';
            app.Cmoy = uieditfield(app.GometrieTab, 'numeric');
            app.Cmoy.ValueDisplayFormat = '%.2f';
            app.Cmoy.Editable = 'off';
            app.AngledeflcheC4radLabel = uilabel(app.GometrieTab);
            app.AngledeflcheC4radLabel.Text = 'Angle de flèche C/4 [rad]';
            app.Angle_fleche_c4 = uieditfield(app.GometrieTab, 'numeric');
            app.Angle_fleche_c4.ValueDisplayFormat = '%.2f';
            app.Angle_fleche_c4.Editable = 'off';
            app.AngledeflcheC2radLabel = uilabel(app.GometrieTab);
            app.AngledeflcheC2radLabel.Text = 'Angle de flèche C/2 [rad]';
            app.Angle_fleche_c2 = uieditfield(app.GometrieTab, 'numeric');
            app.Angle_fleche_c2.ValueDisplayFormat = '%.2f';
            app.Angle_fleche_c2.Editable = 'off';
            app.AerodynamismeTab = uitab(app.TabGroup3);
            app.AerodynamismeTab.Title = 'Aerodynamisme';
            app.UIAxes = uiaxes(app.AerodynamismeTab);
            title(app.UIAxes, 'Portance')
            xlabel(app.UIAxes, 'Angle d''Attaque')
            ylabel(app.UIAxes, 'Portance')
            app.AngledeCalageradEditFieldLabel = uilabel(app.AerodynamismeTab);
            app.AngledeCalageradEditFieldLabel.Text = 'Angle de Calage [rad]';
            app.angle_calage_aile = uieditfield(app.AerodynamismeTab, 'numeric');
            app.angle_calage_aile.ValueDisplayFormat = '%.3f';
            app.angle_calage_aile.Editable = 'off';
            app.acoeffdiraileEditFieldLabel = uilabel(app.AerodynamismeTab);
            app.acoeffdiraileEditFieldLabel.Text = 'a (coeff dir aile)';
            app.a_aile = uieditfield(app.AerodynamismeTab, 'numeric');
            app.a_aile.ValueDisplayFormat = '%.3f';
            app.a_aile.Editable = 'off';
            app.a0coeffdirprofilEditFieldLabel = uilabel(app.AerodynamismeTab);
            app.a0coeffdirprofilEditFieldLabel.Text = 'a0 (coeff dir profil)';
            app.a0_aile = uieditfield(app.AerodynamismeTab, 'numeric');
            app.a0_aile.ValueDisplayFormat = '%.3f';
            app.a0_aile.Editable = 'off';
            app.CLdecroisireLabel = uilabel(app.AerodynamismeTab);
            app.CLdecroisireLabel.Text = 'CL de croisière';
            app.CL_cruise = uieditfield(app.AerodynamismeTab, 'numeric');
            app.CL_cruise.ValueDisplayFormat = '%.3f';
            app.CL_cruise.Editable = 'off';
            app.ReynoldsLabel = uilabel(app.AerodynamismeTab);
            app.ReynoldsLabel.Text = 'Reynolds';
            app.Re_aile = uieditfield(app.AerodynamismeTab, 'numeric');
            app.Re_aile.ValueDisplayFormat = '%7.0f';
            app.Re_aile.Editable = 'off';
            app.CD0Label = uilabel(app.AerodynamismeTab);
            app.CD0Label.Text = 'CD0';
            app.CD0_aile = uieditfield(app.AerodynamismeTab, 'numeric');
            app.CD0_aile.ValueDisplayFormat = '%.4f';
            app.CD0_aile.Editable = 'off';
            app.FuselageTab = uitab(app.TabGroup2);
            app.FuselageTab.Title = 'Fuselage';
            app.ReynoldsEditFieldLabel = uilabel(app.FuselageTab);
            app.ReynoldsEditFieldLabel.Text = 'Reynolds';
            app.Re_fuslage = uieditfield(app.FuselageTab, 'numeric');
            app.Re_fuslage.ValueDisplayFormat = '%7.0f';
            app.Re_fuslage.Editable = 'off';
            app.CD0Label_2 = uilabel(app.FuselageTab);
            app.CD0Label_2.Text = 'CD0';
            app.CD0_fuslage = uieditfield(app.FuselageTab, 'numeric');
            app.CD0_fuslage.ValueDisplayFormat = '%.4f';
            app.CD0_fuslage.Editable = 'off';
            app.EmpenageTab = uitab(app.TabGroup2);
            app.EmpenageTab.Title = 'Empenage';
            app.TabGroup4 = uitabgroup(app.EmpenageTab);
            app.GomtrieTab = uitab(app.TabGroup4);
            app.GomtrieTab.Title = 'Géométrie';
            app.HorizontalLabel_2 = uilabel(app.GomtrieTab);
            app.HorizontalLabel_2.Text = 'Horizontal';
            app.VerticalLabel_2 = uilabel(app.GomtrieTab);
            app.VerticalLabel_2.Text = 'Vertical';
            app.Surfaceft2EditFieldLabel_2 = uilabel(app.GomtrieTab);
            app.Surfaceft2EditFieldLabel_2.Text = 'Surface [ft^2]';
            app.S_HT = uieditfield(app.GomtrieTab, 'numeric');
            app.S_HT.ValueDisplayFormat = '%.2f';
            app.S_HT.Editable = 'off';
            app.EnvergureftEditFieldLabel = uilabel(app.GomtrieTab);
            app.EnvergureftEditFieldLabel.Text = 'Envergure [ft]';
            app.b_ht = uieditfield(app.GomtrieTab, 'numeric');
            app.b_ht.ValueDisplayFormat = '%.2f';
            app.b_ht.Editable = 'off';
            app.CordelemplantureftEditFieldLabel = uilabel(app.GomtrieTab);
            app.CordelemplantureftEditFieldLabel.Text = 'Corde à l''emplanture  [ft]';
            app.cr_ht = uieditfield(app.GomtrieTab, 'numeric');
            app.cr_ht.ValueDisplayFormat = '%.2f';
            app.cr_ht.Editable = 'off';
            app.CordeauboutftEditFieldLabel = uilabel(app.GomtrieTab);
            app.CordeauboutftEditFieldLabel.Text = 'Corde au bout [ft]';
            app.ct_ht = uieditfield(app.GomtrieTab, 'numeric');
            app.ct_ht.ValueDisplayFormat = '%.2f';
            app.ct_ht.Editable = 'off';
            app.CordeMoyenneftEditFieldLabel = uilabel(app.GomtrieTab);
            app.CordeMoyenneftEditFieldLabel.Text = 'Corde Moyenne [ft]';
            app.cm_ht = uieditfield(app.GomtrieTab, 'numeric');
            app.cm_ht.ValueDisplayFormat = '%.2f';
            app.cm_ht.Editable = 'off';
            app.Surfaceft2EditField_2Label = uilabel(app.GomtrieTab);
            app.Surfaceft2EditField_2Label.Text = 'Surface [ft^2]';
            app.S_VT = uieditfield(app.GomtrieTab, 'numeric');
            app.S_VT.ValueDisplayFormat = '%.2f';
            app.S_VT.Editable = 'off';
            app.EnvergureftEditField_2Label = uilabel(app.GomtrieTab);
            app.EnvergureftEditField_2Label.Text = 'Envergure [ft]';
            app.b_vt = uieditfield(app.GomtrieTab, 'numeric');
            app.b_vt.ValueDisplayFormat = '%.2f';
            app.b_vt.Editable = 'off';
            app.CordelemplantureftEditField_2Label = uilabel(app.GomtrieTab);
            app.CordelemplantureftEditField_2Label.Text = 'Corde à l''emplanture  [ft]';
            app.cr_vt = uieditfield(app.GomtrieTab, 'numeric');
            app.cr_vt.ValueDisplayFormat = '%.2f';
            app.cr_vt.Editable = 'off';
            app.CordeauboutftEditField_2Label = uilabel(app.GomtrieTab);
            app.CordeauboutftEditField_2Label.Text = 'Corde au bout [ft]';
            app.ct_vt = uieditfield(app.GomtrieTab, 'numeric');
            app.ct_vt.ValueDisplayFormat = '%.2f';
            app.ct_vt.Editable = 'off';
            app.CordeMoyenneftEditField_2Label = uilabel(app.GomtrieTab);
            app.CordeMoyenneftEditField_2Label.Text = 'Corde Moyenne [ft]';
            app.cm_vt = uieditfield(app.GomtrieTab, 'numeric');
            app.cm_vt.ValueDisplayFormat = '%.2f';
            app.cm_vt.Editable = 'off';
            app.AngledeFlcheC4Label = uilabel(app.GomtrieTab);
            app.AngledeFlcheC4Label.Text = 'Angle de Flèche C/4';
            app.Angleflechec4_ht = uieditfield(app.GomtrieTab, 'numeric');
            app.Angleflechec4_ht.ValueDisplayFormat = '%.2f';
            app.Angleflechec4_ht.Editable = 'off';
            app.AngledeFlcheC2Label = uilabel(app.GomtrieTab);
            app.AngledeFlcheC2Label.Text = 'Angle de Flèche C/2';
            app.Angleflechec2_ht = uieditfield(app.GomtrieTab, 'numeric');
            app.Angleflechec2_ht.ValueDisplayFormat = '%.2f';
            app.Angleflechec2_ht.Editable = 'off';
            app.AngledeFlcheC4Label_2 = uilabel(app.GomtrieTab);
            app.AngledeFlcheC4Label_2.Text = 'Angle de Flèche C/4';
            app.Angleflechec4_vt = uieditfield(app.GomtrieTab, 'numeric');
            app.Angleflechec4_vt.ValueDisplayFormat = '%.2f';
            app.Angleflechec4_vt.Editable = 'off';
            app.AngledeFlcheC2Label_2 = uilabel(app.GomtrieTab);
            app.AngledeFlcheC2Label_2.Text = 'Angle de Flèche C/2';
            app.Angleflechec2_vt = uieditfield(app.GomtrieTab, 'numeric');
            app.Angleflechec2_vt.ValueDisplayFormat = '%.2f';
            app.Angleflechec2_vt.Editable = 'off';
            app.ArodynamismeTab = uitab(app.TabGroup4);
            app.ArodynamismeTab.Title = 'Aérodynamisme';
            app.HorizontalLabel_3 = uilabel(app.ArodynamismeTab);
            app.HorizontalLabel_3.Text = 'Horizontal';
            app.VerticalLabel_3 = uilabel(app.ArodynamismeTab);
            app.VerticalLabel_3.Text = 'Vertical';
            app.Cd0Label = uilabel(app.ArodynamismeTab);
            app.Cd0Label.Text = 'Cd0';
            app.Cd0_ht = uieditfield(app.ArodynamismeTab, 'numeric');
            app.Cd0_ht.ValueDisplayFormat = '%.3f';
            app.Cd0_ht.Editable = 'off';
            app.Cd0Label_2 = uilabel(app.ArodynamismeTab);
            app.Cd0Label_2.Text = 'Cd0';
            app.Cd0_vt = uieditfield(app.ArodynamismeTab, 'numeric');
            app.Cd0_vt.ValueDisplayFormat = '%.3f';
            app.Cd0_vt.Editable = 'off';
            app.aLabel = uilabel(app.ArodynamismeTab);
            app.aLabel.Text = 'a';
            app.a_ht = uieditfield(app.ArodynamismeTab, 'numeric');
            app.a_ht.ValueDisplayFormat = '%.3f';
            app.a_ht.Editable = 'off';
            app.aLabel_2 = uilabel(app.ArodynamismeTab);
            app.aLabel_2.Text = 'a';
            app.a_vt = uieditfield(app.ArodynamismeTab, 'numeric');
            app.a_vt.ValueDisplayFormat = '%.3f';
            app.a_vt.Editable = 'off';
            app.UIAxesEmpennage = uiaxes(app.ArodynamismeTab);
            title(app.UIAxesEmpennage, 'Portance Empennage')
            xlabel(app.UIAxesEmpennage, 'Angle d''attaque')
            ylabel(app.UIAxesEmpennage, 'Portance')
            app.TainedOndeTab = uitab(app.TabGroup2);
            app.TainedOndeTab.Title = 'Tainée d''Onde';
            app.UIAxesDw = uiaxes(app.TainedOndeTab);
            title(app.UIAxesDw, 'Trainée d''onde')
            xlabel(app.UIAxesDw, 'Mach')
            ylabel(app.UIAxesDw, 'Cdw')
            app.Ma_critique = uieditfield(app.TainedOndeTab, 'numeric');
            app.Ma_critique.ValueDisplayFormat = '%.3f';
            app.Ma_critique.Editable = 'off';
            app.MachLabel = uilabel(app.TainedOndeTab);
            app.MachLabel.Text = 'Mach';
            app.Ma_DD = uieditfield(app.TainedOndeTab, 'numeric');
            app.Ma_DD.ValueDisplayFormat = '%.3f';
            app.Ma_DD.Editable = 'off';
            app.M_100 = uieditfield(app.TainedOndeTab, 'numeric');
            app.M_100.ValueDisplayFormat = '%.3f';
            app.M_100.Editable = 'off';
            app.M_100.Value = 1;
            app.M_105 = uieditfield(app.TainedOndeTab, 'numeric');
            app.M_105.ValueDisplayFormat = '%.3f';
            app.M_105.Editable = 'off';
            app.M_105.Value = 1.05;
            app.M_120 = uieditfield(app.TainedOndeTab, 'numeric');
            app.M_120.ValueDisplayFormat = '%.3f';
            app.M_120.Editable = 'off';
            app.M_120.Value = 1.2;
            app.MaCrLabel = uilabel(app.TainedOndeTab);
            app.MaCrLabel.Text = 'MaCr';
            app.MaDDLabel = uilabel(app.TainedOndeTab);
            app.MaDDLabel.Text = 'MaDD';
            app.Cdw_cr = uieditfield(app.TainedOndeTab, 'numeric');
            app.Cdw_cr.ValueDisplayFormat = '%.3f';
            app.Cdw_cr.Editable = 'off';
            app.CdwLabel = uilabel(app.TainedOndeTab);
            app.CdwLabel.Text = 'Cdw';
            app.Cdw_dd = uieditfield(app.TainedOndeTab, 'numeric');
            app.Cdw_dd.ValueDisplayFormat = '%.3f';
            app.Cdw_dd.Editable = 'off';
            app.Cdw_dd.Value = 0.002;
            app.Cdw_100 = uieditfield(app.TainedOndeTab, 'numeric');
            app.Cdw_100.ValueDisplayFormat = '%.3f';
            app.Cdw_100.Editable = 'off';
            app.Cdw_105 = uieditfield(app.TainedOndeTab, 'numeric');
            app.Cdw_105.ValueDisplayFormat = '%.3f';
            app.Cdw_105.Editable = 'off';
            app.Cdw_120 = uieditfield(app.TainedOndeTab, 'numeric');
            app.Cdw_120.ValueDisplayFormat = '%.3f';
            app.Cdw_120.Editable = 'off';
            app.dC_dM_cr = uieditfield(app.TainedOndeTab, 'numeric');
            app.dC_dM_cr.ValueDisplayFormat = '%.3f';
            app.dC_dM_cr.Editable = 'off';
            app.dCdwdMachLabel = uilabel(app.TainedOndeTab);
            app.dCdwdMachLabel.Text = 'dCdw/dMach';
            app.dC_dM_dd = uieditfield(app.TainedOndeTab, 'numeric');
            app.dC_dM_dd.ValueDisplayFormat = '%.3f';
            app.dC_dM_dd.Editable = 'off';
            app.dC_dM_100 = uieditfield(app.TainedOndeTab, 'numeric');
            app.dC_dM_100.ValueDisplayFormat = '%.3f';
            app.dC_dM_100.Editable = 'off';
            app.dC_dM_100.Value = 1;
            app.dC_dM_105 = uieditfield(app.TainedOndeTab, 'numeric');
            app.dC_dM_105.ValueDisplayFormat = '%.3f';
            app.dC_dM_105.Editable = 'off';
            app.dC_dM_105.Value = 1.05;
            app.dC_dM_120 = uieditfield(app.TainedOndeTab, 'numeric');
            app.dC_dM_120.ValueDisplayFormat = '%.3f';
            app.dC_dM_120.Editable = 'off';
            app.dC_dM_120.Value = 1.2;
            app.cdw_coeff_cr = uieditfield(app.TainedOndeTab, 'numeric');
            app.cdw_coeff_cr.ValueDisplayFormat = '%.3f';
            app.cdw_coeff_cr.Editable = 'off';
            app.cdw_coeff_cr.Value = 1;
            app.CoeffLabel = uilabel(app.TainedOndeTab);
            app.CoeffLabel.Text = 'Coeff';
            app.cdw_coeff_dd = uieditfield(app.TainedOndeTab, 'numeric');
            app.cdw_coeff_dd.ValueDisplayFormat = '%.3f';
            app.cdw_coeff_dd.ValueChangedFcn = createCallbackFcn(app, @Ma_DD_Cl0ValueChanged, true);
            app.cdw_coeff_dd.Value = 1;
            app.cdw_coeff_100 = uieditfield(app.TainedOndeTab, 'numeric');
            app.cdw_coeff_100.ValueDisplayFormat = '%.3f';
            app.cdw_coeff_100.ValueChangedFcn = createCallbackFcn(app, @Ma_DD_Cl0ValueChanged, true);
            app.cdw_coeff_100.Value = 1;
            app.cdw_coeff_105 = uieditfield(app.TainedOndeTab, 'numeric');
            app.cdw_coeff_105.ValueDisplayFormat = '%.3f';
            app.cdw_coeff_105.ValueChangedFcn = createCallbackFcn(app, @Ma_DD_Cl0ValueChanged, true);
            app.cdw_coeff_105.Value = 1;
            app.cdw_coeff_120 = uieditfield(app.TainedOndeTab, 'numeric');
            app.cdw_coeff_120.ValueDisplayFormat = '%.3f';
            app.cdw_coeff_120.Editable = 'off';
            app.cdw_coeff_120.Value = 1;
            app.CDwencroisireEditFieldLabel = uilabel(app.TainedOndeTab);
            app.CDwencroisireEditFieldLabel.Text = 'CDw en croisière';
            app.Cdw = uieditfield(app.TainedOndeTab, 'numeric');
            app.Cdw.ValueDisplayFormat = '%.3f';
            app.Cdw.Editable = 'off';
            app.MachDDCl0EditFieldLabel = uilabel(app.TainedOndeTab);
            app.MachDDCl0EditFieldLabel.Text = 'Mach DD Cl=0';
            app.Ma_DD_Cl0 = uieditfield(app.TainedOndeTab, 'numeric');
            app.Ma_DD_Cl0.ValueDisplayFormat = '%.3f';
            app.Ma_DD_Cl0.ValueChangedFcn = createCallbackFcn(app, @Ma_DD_Cl0ValueChanged, true);
            app.PerformanceTab = uitab(app.TabGroup2);
            app.PerformanceTab.Title = 'Performance';
            app.FinessemaxLabel = uilabel(app.PerformanceTab);
            app.FinessemaxLabel.Text = 'Finesse max';
            app.Finesse = uieditfield(app.PerformanceTab, 'numeric');
            app.Finesse.ValueDisplayFormat = '%.2f';
            app.Finesse.Editable = 'off';
            app.MachRelLabel = uilabel(app.PerformanceTab);
            app.MachRelLabel.Text = 'Mach Réel';
            app.M_real = uieditfield(app.PerformanceTab, 'numeric');
            app.M_real.ValueDisplayFormat = '%.2f';
            app.M_real.Editable = 'off';
            app.AngledeMontradLabel = uilabel(app.PerformanceTab);
            app.AngledeMontradLabel.Text = 'Angle de Monté [rad]';
            app.angle_climb = uieditfield(app.PerformanceTab, 'numeric');
            app.angle_climb.ValueDisplayFormat = '%.2f';
            app.angle_climb.Editable = 'off';
            app.CLmaxLandingLabel = uilabel(app.PerformanceTab);
            app.CLmaxLandingLabel.Text = 'CL max (Landing)';
            app.CL_max_landing = uieditfield(app.PerformanceTab, 'numeric');
            app.CL_max_landing.ValueDisplayFormat = '%.2f';
            app.CL_max_landing.Editable = 'off';
            app.CLmaxTakeoffLabel = uilabel(app.PerformanceTab);
            app.CLmaxTakeoffLabel.Text = 'CL max (Takeoff)';
            app.CL_max_TO = uieditfield(app.PerformanceTab, 'numeric');
            app.CL_max_TO.ValueDisplayFormat = '%.2f';
            app.CL_max_TO.Editable = 'off';
            app.DistancedeDcollageftLabel = uilabel(app.PerformanceTab);
            app.DistancedeDcollageftLabel.Text = 'Distance de Décollage [ft]';
            app.DTO = uieditfield(app.PerformanceTab, 'numeric');
            app.DTO.ValueDisplayFormat = '%.2f';
            app.DTO.Editable = 'off';
            app.VitessededcrochageftsLabel = uilabel(app.PerformanceTab);
            app.VitessededcrochageftsLabel.Text = 'Vitesse de décrochage [ft/s]';
            app.Vstall = uieditfield(app.PerformanceTab, 'numeric');
            app.Vstall.ValueDisplayFormat = '%.2f';
            app.Vstall.Editable = 'off';
            app.VitessededcollageftsLabel = uilabel(app.PerformanceTab);
            app.VitessededcollageftsLabel.Text = 'Vitesse de décollage [ft/s]';
            app.VTo = uieditfield(app.PerformanceTab, 'numeric');
            app.VTo.ValueDisplayFormat = '%.2f';
            app.VTo.Editable = 'off';
            app.TempratureKLabel = uilabel(app.PerformanceTab);
            app.TempratureKLabel.Text = 'Température [K]';
            app.T = uieditfield(app.PerformanceTab, 'numeric');
            app.T.ValueDisplayFormat = '%.2f';
            app.T.Editable = 'off';
            app.VitessedeCroisireftsLabel = uilabel(app.PerformanceTab);
            app.VitessedeCroisireftsLabel.Text = 'Vitesse de Croisière [ft/s]';
            app.V_cruise = uieditfield(app.PerformanceTab, 'numeric');
            app.V_cruise.ValueDisplayFormat = '%.2f';
            app.V_cruise.Editable = 'off';
            app.CD0delavionLabel = uilabel(app.PerformanceTab);
            app.CD0delavionLabel.Text = 'CD0 de l''avion';
            app.Cd0_avion = uieditfield(app.PerformanceTab, 'numeric');
            app.Cd0_avion.ValueDisplayFormat = '%.3f';
            app.Cd0_avion.Editable = 'off';
            app.Finesse_iter = uieditfield(app.PerformanceTab, 'numeric');
            app.Finesse_iter.ValueDisplayFormat = '%.2f';
            app.Finesse_iter.Editable = 'off';
            app.ThLabel = uilabel(app.PerformanceTab);
            app.ThLabel.Text = 'Th';
            app.CalLabel = uilabel(app.PerformanceTab);
            app.CalLabel.Text = 'Cal';
            app.RsultatsdeCalculLabel = uilabel(app.DesigndaronefUIFigure);
            app.RsultatsdeCalculLabel.Text = 'Résultats de Calcul';
            app.ParamtresUtilisateurLabel = uilabel(app.DesigndaronefUIFigure);
            app.ParamtresUtilisateurLabel.Text = 'Paramètres Utilisateur';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DesignAeronef

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DesigndaronefUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DesigndaronefUIFigure)
        end
    end
end